classdef appLab1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        CalcularButton       matlab.ui.control.Button
        YawEditField         matlab.ui.control.NumericEditField
        YawEditFieldLabel    matlab.ui.control.Label
        PitchEditField       matlab.ui.control.NumericEditField
        PitchEditFieldLabel  matlab.ui.control.Label
        RollEditField        matlab.ui.control.NumericEditField
        RollEditFieldLabel   matlab.ui.control.Label
        zEditField           matlab.ui.control.NumericEditField
        zEditFieldLabel      matlab.ui.control.Label
        yEditField           matlab.ui.control.NumericEditField
        yEditFieldLabel      matlab.ui.control.Label
        xEditField           matlab.ui.control.NumericEditField
        xEditFieldLabel      matlab.ui.control.Label
        IngresarposeLabel    matlab.ui.control.Label
        EditField_6          matlab.ui.control.NumericEditField
        EditField_5          matlab.ui.control.NumericEditField
        EditField_4          matlab.ui.control.NumericEditField
        EditField_3          matlab.ui.control.NumericEditField
        EditField_2          matlab.ui.control.NumericEditField
        EditField            matlab.ui.control.NumericEditField
        EFLabelangy          matlab.ui.control.Label
        EFLabelangp          matlab.ui.control.Label
        EFLabelangr          matlab.ui.control.Label
        AngulosRPYLabel      matlab.ui.control.Label
        EFLabelz             matlab.ui.control.Label
        EFLabely             matlab.ui.control.Label
        EFLabelx             matlab.ui.control.Label
        EfectorfinalLabel    matlab.ui.control.Label
        q6Slider             matlab.ui.control.Slider
        q6SliderLabel        matlab.ui.control.Label
        q5Slider             matlab.ui.control.Slider
        q5SliderLabel        matlab.ui.control.Label
        q4Slider             matlab.ui.control.Slider
        q4SliderLabel        matlab.ui.control.Label
        q3Slider             matlab.ui.control.Slider
        q3SliderLabel        matlab.ui.control.Label
        q2Slider             matlab.ui.control.Slider
        q2SliderLabel        matlab.ui.control.Label
        q1Slider             matlab.ui.control.Slider
        q1SliderLabel        matlab.ui.control.Label
    end

properties (Access = public)
        robot_1 % Robot
        q1_=0
        q2_=0
        q3_=0
        q4_=0
        q5_=0
        q6_=0
        fig
    end
    
    methods (Access = private)
        
        function updateRobotPlot(app)
            config = deg2rad([app.q1_,app.q2_,app.q3_,app.q4_,app.q5_,app.q6_]);
            app.robot_1.plot(config,'workspace',[-10 10 -10 10 -1 12],'noa', 'view',[50 50])
                   
        end


        function setEFLabelText(app)
            config = deg2rad([app.q1_,app.q2_,app.q3_,app.q4_,app.q5_,app.q6_]);
            t_matrix = app.robot_1.fkine(config);
            vars = t_matrix(1:3,4);
            
            app.EFLabelx.Text = "x: " + string(vars(1));
            app.EFLabely.Text = "y: " + string(vars(2));
            app.EFLabelz.Text = "z: " + string(vars(3));

            rpy = round(tr2rpy(t_matrix,'deg'),4);
            app.EFLabelangr.Text = string(rpy(1)) + " deg";
            app.EFLabelangp.Text = string(rpy(2)) + " deg";
            app.EFLabelangy.Text = string(rpy(3)) + " deg";
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            L1 = 2.45;
            L2 = 3.00;
            L3 = 7.10;
            L4 = 3.00;
            L5 = 5.40;
            L6 = 1.50;
            L7 = 1.60;
            
            L(1) = Link('revolute','a',0,'alpha',0,'d',L1,'offset',0,'modified','qlim',[-pi,pi]);
            L(2) = Link('revolute','a',0,'alpha',pi/2,'d',-L2,'offset',pi/2,'modified','qlim',[-pi,pi]);
            L(3) = Link('revolute','a',L3,'alpha',pi,'d',-L4,'offset',0,'modified','qlim',[-3*pi/2,3*pi/2]);
            L(4) = Link('revolute','a',0,'alpha',-pi/2,'d',-L5,'offset',0,'modified','qlim',[-pi,pi]);
            L(5) = Link('revolute','a',0,'alpha',pi/2,'d',L6,'offset',0,'modified','qlim',[-pi,pi]);
            L(6) = Link('revolute','a',0,'alpha',-pi/2,'d',0,'offset',0,'modified','qlim',[-pi,pi]);
            
            % Cinemática directa
            A_tool = transl(0,0,-L7)*round(trotx(pi),2);
            app.robot_1 = SerialLink(L,'name','CRX-10iA');
            app.robot_1.tool = A_tool;
      
            app.fig = figure();
            updateRobotPlot(app)
            movegui(app.fig,'center');
            app.fig.WindowState = 'maximized';
            setEFLabelText(app)
        end

        % Value changing function: q6Slider
        function updateq6(app, event)
            app.q6_= app.q6Slider.Value;
            updateRobotPlot(app)
            setEFLabelText(app);
            app.EditField_6.Value = app.q6_;
        end

        % Value changing function: q5Slider
        function updateq5(app, event)
            app.q5_= app.q5Slider.Value;
            updateRobotPlot(app)
            setEFLabelText(app);
            app.EditField_5.Value = app.q5_;
        end

        % Value changing function: q4Slider
        function updateq4(app, event)
            app.q4_= app.q4Slider.Value;
            updateRobotPlot(app)
            setEFLabelText(app);
            app.EditField_4.Value = app.q4_;
        end

        % Value changing function: q3Slider
        function updateq3(app, event)
            app.q3_= app.q3Slider.Value;
            updateRobotPlot(app)
            setEFLabelText(app);
            app.EditField_3.Value = app.q3_;
        end

        % Value changing function: q2Slider
        function updateq2(app, event)
            app.q2_= app.q2Slider.Value;
            updateRobotPlot(app)
            setEFLabelText(app);
            app.EditField_2.Value = app.q2_;
        end

        % Callback function: q1Slider, q1Slider
        function updateq1(app, event)
            app.q1_= app.q1Slider.Value;
            updateRobotPlot(app)
            setEFLabelText(app);
            app.EditField.Value = app.q1_;
        end

        % Value changed function: EditField
        function q1Input(app, event)
            value = app.EditField.Value;
            app.q1Slider.Value = value;
            updateq1(app,event)
        end

        % Value changed function: EditField_2
        function q2Input(app, event)
            value = app.EditField_2.Value;
            app.q2Slider.Value = value;
            updateq2(app,event)
        end

        % Value changed function: EditField_3
        function q3Input(app, event)
            value = app.EditField_3.Value;
            app.q3Slider.Value = value;
            updateq3(app,event)
        end

        % Value changed function: EditField_4
        function q4Input(app, event)
            value = app.EditField_4.Value;
            app.q4Slider.Value = value;
            updateq4(app,event)
        end

        % Value changed function: EditField_5
        function q5Input(app, event)
            value = app.EditField_5.Value;
            app.q5Slider.Value = value;
            updateq5(app,event)
        end

        % Value changed function: EditField_6
        function q6Input(app, event)
            value = app.EditField_6.Value;
            app.q6Slider.Value = value;
            updateq6(app,event)
        end

        % Button pushed function: CalcularButton
        function inverseKinematics(app, event)
            x_value = app.xEditField.Value;
            y_value = app.yEditField.Value;
            z_value = app.zEditField.Value;
            roll_value = app.RollEditField.Value;
            pitch_value = app.PitchEditField.Value;
            yaw_value = app.YawEditField.Value;
            
            T = transl(x_value,y_value,z_value)*rpy2tr(roll_value,pitch_value,yaw_value,'deg');
            q_solution = rad2deg(IKIN(T));
            
            app.EditField.Value = q_solution(1);
            app.EditField_2.Value = q_solution(2);
            app.EditField_3.Value = q_solution(3);
            app.EditField_4.Value = q_solution(4);
            app.EditField_5.Value = q_solution(5);
            app.EditField_6.Value = q_solution(6);
            
            q1Input(app,event)
            q2Input(app,event)
            q3Input(app,event)
            q4Input(app,event)
            q5Input(app,event)
            q6Input(app,event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.Limits = [-180 180];
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @updateq1, true);
            app.q1Slider.ValueChangingFcn = createCallbackFcn(app, @updateq1, true);
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.Limits = [-180 180];
            app.q2Slider.ValueChangingFcn = createCallbackFcn(app, @updateq2, true);
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.Limits = [-270 270];
            app.q3Slider.ValueChangingFcn = createCallbackFcn(app, @updateq3, true);
            app.q4SliderLabel = uilabel(app.UIFigure);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.UIFigure);
            app.q4Slider.Limits = [-180 180];
            app.q4Slider.ValueChangingFcn = createCallbackFcn(app, @updateq4, true);
            app.q5SliderLabel = uilabel(app.UIFigure);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.UIFigure);
            app.q5Slider.Limits = [-180 180];
            app.q5Slider.ValueChangingFcn = createCallbackFcn(app, @updateq5, true);
            app.q6SliderLabel = uilabel(app.UIFigure);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.UIFigure);
            app.q6Slider.Limits = [-180 180];
            app.q6Slider.ValueChangingFcn = createCallbackFcn(app, @updateq6, true);
            app.EfectorfinalLabel = uilabel(app.UIFigure);
            app.EfectorfinalLabel.Text = 'Efector final: ';
            app.EFLabelx = uilabel(app.UIFigure);
            app.EFLabelx.Text = '';
            app.EFLabely = uilabel(app.UIFigure);
            app.EFLabely.Text = '';
            app.EFLabelz = uilabel(app.UIFigure);
            app.EFLabelz.Text = '';
            app.AngulosRPYLabel = uilabel(app.UIFigure);
            app.AngulosRPYLabel.Text = 'Angulos RPY';
            app.EFLabelangr = uilabel(app.UIFigure);
            app.EFLabelangr.Text = '';
            app.EFLabelangp = uilabel(app.UIFigure);
            app.EFLabelangp.Text = '';
            app.EFLabelangy = uilabel(app.UIFigure);
            app.EFLabelangy.Text = '';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @q1Input, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @q2Input, true);
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @q3Input, true);
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @q4Input, true);
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @q5Input, true);
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @q6Input, true);
            app.IngresarposeLabel = uilabel(app.UIFigure);
            app.IngresarposeLabel.Text = 'Ingresar pose';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.YawEditFieldLabel = uilabel(app.UIFigure);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @inverseKinematics, true);
            app.CalcularButton.Text = 'Calcular';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appLab1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end